classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        rightyaxisDropDown            matlab.ui.control.DropDown
        rightyaxisDropDownLabel       matlab.ui.control.Label
        leftyaxisDropDown             matlab.ui.control.DropDown
        leftyaxisDropDownLabel        matlab.ui.control.Label
        MergedFileNameEditField       matlab.ui.control.EditField
        MergedFileNameEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        plotButton                    matlab.ui.control.Button
        mergeButton                   matlab.ui.control.Button
        deleteButton                  matlab.ui.control.Button
        ListBox                       matlab.ui.control.ListBox
        Label                         matlab.ui.control.Label
        loadButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileNames = {}% the Filenames of the 
        folder % the folder where the merged file should be stored
        startposition = 2^31;
        size 
        f = figure;
      
    end
    
    properties (Access = public)
        data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [fileName,path] = uigetfile("*.mat");
            app.folder = path;
            app.ListBox.Enable = 'on';
            app.fileNames = [app.fileNames fileName];
            app.ListBox.Items = app.fileNames;
            
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
           k = find(strcmp(app.fileNames,app.ListBox.Value));
           app.fileNames(k) =[];
           app.ListBox.Items = app.fileNames;
        end

        % Button pushed function: mergeButton
        function mergeButtonPushed(app, event)
            merged = [];
            app.data = [];
          for i = 1:length(app.fileNames)
            file = load([app.folder,app.fileNames{i}]).measurement;
            if file.engine_timestamp_4_Timestamp1000_(100) < app.startposition
               app.startposition = file.engine_timestamp_4_Timestamp1000_(100);
            end
            test = resampleTest(app.FrequencyEditField.Value,file);
            test = struct2table(test);
            test = filterOutBadData(test,app.startposition);
            if i == 1
                merged = test;
            else 
                merged = mergeData(merged,test);
            end
          end
          merged = sortrows(merged,"engine_timestamp_4_Timestamp1000_","ascend");
          name = append(app.folder,'\',app.MergedFileNameEditField.Value,".mat");
          save(name,"merged")
          app.data = merged;
          app.data= createTimeline(app.data,app.FrequencyEditField.Value);
          dropdown = fieldnames(app.data);
          app.leftyaxisDropDown.Items = dropdown(1:length(dropdown)-4);
          app.rightyaxisDropDown.Items = dropdown(1:length(dropdown)-4);
          app.data = filterOutBadData(app.data,app.startposition);
          app.data = table2struct(app.data,'ToScalar',true);
           app.UIAxes.XLabel.String = "Time";  
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            clf('reset');
            hold on;
            yyaxis left;
            ylabel(app.leftyaxisDropDown.Value,'Interpreter','none');
            xlabel("time");
            yvalue =app.data.(app.leftyaxisDropDown.Value);
            xvalue = app.data.timeline;
            plot(xvalue,yvalue); 
            hold off;
            hold on;
            yyaxis right;
            ylabel(app.rightyaxisDropDown.Value,'Interpreter','none');
            plot(app.data.timeline,app.data.(app.rightyaxisDropDown.Value));
            hold off;
            legend(app.leftyaxisDropDown.Value,app.rightyaxisDropDown.Value,'Interpreter','none');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.deleteButton = uibutton(app.UIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete';
            app.mergeButton = uibutton(app.UIFigure, 'push');
            app.mergeButton.ButtonPushedFcn = createCallbackFcn(app, @mergeButtonPushed, true);
            app.mergeButton.Text = 'merge';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = {'plot'; ''};
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.Value = 1;
            app.MergedFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.MergedFileNameEditFieldLabel.Text = 'Merged File Name';
            app.MergedFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.MergedFileNameEditField.Value = 'standard';
            app.leftyaxisDropDownLabel = uilabel(app.UIFigure);
            app.leftyaxisDropDownLabel.Text = 'left y axis';
            app.leftyaxisDropDown = uidropdown(app.UIFigure);
            app.rightyaxisDropDownLabel = uilabel(app.UIFigure);
            app.rightyaxisDropDownLabel.Text = 'right y axis';
            app.rightyaxisDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end